classdef cerestim_dbs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CereStimDBSUIFigure        matlab.ui.Figure
        RefreshButton              matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField       matlab.ui.control.NumericEditField
        FinalDursEditFieldLabel    matlab.ui.control.Label
        FinalDursEditField         matlab.ui.control.NumericEditField
        RampDursEditFieldLabel     matlab.ui.control.Label
        RampDursEditField          matlab.ui.control.NumericEditField
        InterphaseDropDownLabel    matlab.ui.control.Label
        InterphaseDropDown         matlab.ui.control.DropDown
        PolarityDropDownLabel      matlab.ui.control.Label
        PolarityDropDown           matlab.ui.control.DropDown
        GenerateButton             matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        WidthuSSpinnerLabel        matlab.ui.control.Label
        WidthuSSpinner             matlab.ui.control.Spinner
        ElectrodeSpinnerLabel      matlab.ui.control.Label
        ElectrodeSpinner           matlab.ui.control.Spinner
        AmplitudeuASpinnerLabel    matlab.ui.control.Label
        AmplitudeuASpinner         matlab.ui.control.Spinner
    end


    properties (Access = private)
        stimulator % Description
        stim_timer % Description
    end

    methods (Access = private)
    
        function params_changed(app)
            b_connected = app.stimulator.isConnected();
            if b_connected
                app.stimulator.stop();
                app.StatusLamp.Color = 'yellow';
                app.StartButton.Enable = 'off';
            end
        end
        
        function [waveform, n_reps] = calc_waveform(app, stim_params)
            %waveform = calc_waveform(app, stim_params)
%             stim_params = struct(...
%                 'freq', app.FrequencyHzEditField.Value,...
%                 'p1_width', app.WidthuSSpinner.Value,...
%                 'p1_amp', p1_amp,...
%                 'dur', app.FinalDursEditField.Value,...
%                 'ramp_dur', app.RampDursEditField.Value,...
%                 'elec', app.ElectrodeSpinner.Value,...
%                 'is_ana', is_ana,...
%                 'is_mono', is_mono,...
%                 'interphase', app.InterphaseDropDown.Value);
            min_interphase = 53;  % It would be great to get this from API.
            cycle_dur_us = 1E6 / stim_params.freq;
            
            if stim_params.is_mono
                [min_amp, ~] = app.stimulator.getMinMaxAmplitude();
                p2_max_width = cycle_dur_us - stim_params.p1_width - 2*min_interphase;
                p2_amp = (stim_params.p1_width * stim_params.p1_amp) / p2_max_width;
                p2_amp = max([p2_amp min_amp]);
                p2_width = (stim_params.p1_width * stim_params.p1_amp) / p2_amp;
            else
                p2_width = stim_params.p1_width;
                p2_amp = stim_params.p1_amp;
            end
            
            if strcmpi(stim_params.interphase, 'Max Sep')
                interphase = (cycle_dur_us - stim_params.p1_width - p2_width) / 2;
            else
                interphase = min_interphase;
            end
            
            % Configure final waveform
            n_pulses = ceil(stim_params.dur * stim_params.freq);
            if n_pulses > 255
                n_reps = ceil(n_pulses / 255);
                n_pulses = 255;
            else
                n_reps = 1;
            end
            
            waveform = {...
                'polarity', int16(stim_params.is_ana),...
                'pulses', n_pulses,...
                'amp1', stim_params.p1_amp,...
                'amp2', p2_amp,...
                'width1', stim_params.p1_width,...
                'width2', p2_width,...
                'interphase', interphase,...
                'frequency', stim_params.freq};
        end
        
        function timer_callback(app, obj, event)
            % The stimulator handles its own timing.
            % Here we just modify the status lamp to reflect
            % that the stimulator (should have) stopped.
            app.StatusLamp.Color = 'blue';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stimulator = cerestim96();
        end

        % Callback function
        function AmplitudeuAEditFieldValueChanged(app, event)
            value = app.AmplitudeuAEditField.Value;
            app.params_changed();
        end

        % Value changed function: AmplitudeuASpinner
        function AmplitudeuASpinnerValueChanged(app, event)
            value = app.AmplitudeuASpinner.Value;
            app.params_changed();
        end

        % Close request function: CereStimDBSUIFigure
        function CereStimDBSUIFigureCloseRequest(app, event)
            app.stimulator.disconnect();
            delete(app.stim_timer);
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            dev_ix = find(strcmpi(app.DropDown.Items, app.DropDown.Value)) - 1;
            app.stimulator.selectDevice(dev_ix);
            if (app.stimulator.isConnected())
                app.stimulator.disconnect();
                app.GenerateButton.Enable = 'off';
                app.ConnectButton.Text = 'Connect';
                app.StatusLamp.Color = 'red';
            else
                app.stimulator.connect();
                app.GenerateButton.Enable = 'on';
                app.ConnectButton.Text = 'Disconnect';
                app.StatusLamp.Color = 'yellow';
            end
        end

        % Callback function
        function ElectrodeEditFieldValueChanged(app, event)
            value = app.ElectrodeEditField.Value;
            app.params_changed();
        end

        % Value changed function: ElectrodeSpinner
        function ElectrodeSpinnerValueChanged(app, event)
            value = app.ElectrodeSpinner.Value;
            app.params_changed();
        end

        % Value changed function: FinalDursEditField
        function FinalDursEditFieldValueChanged(app, event)
            value = app.FinalDursEditField.Value;
            app.params_changed();
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            app.params_changed();            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Parse GUI values and create param struct.
            is_ana = any(strcmpi(app.PolarityDropDown.Value,...
                {'Anodic First', 'Anodic-Mono'}));
            is_mono = any(strcmpi(app.PolarityDropDown.Value,...
                {'Cathodic-Mono', 'Anodic-Mono'}));
            [min_amp, max_amp] = app.stimulator.getMinMaxAmplitude();
            p1_amp = max([min([app.AmplitudeuASpinner.Value max_amp]) min_amp]);
            stim_params = struct(...
                'freq', app.FrequencyHzEditField.Value,...
                'p1_width', app.WidthuSSpinner.Value,...
                'p1_amp', p1_amp,...
                'dur', app.FinalDursEditField.Value - app.RampDursEditField.Value,...
                'elec', app.ElectrodeSpinner.Value,...
                'is_ana', is_ana,...
                'is_mono', is_mono,...
                'interphase', app.InterphaseDropDown.Value);
            
            [final_waveform, n_final_stims] = app.calc_waveform(stim_params);
            
            app.stimulator.setStimPattern(...
                'waveform', 15,...
                final_waveform{:});
            
            % Calculate ramp and add in waveform positions 1-14
            ramp_wfs = cell(0, 2);
            if app.RampDursEditField.Value > 0
                ramp_params = stim_params;
                ramp_params.dur = app.RampDursEditField.Value / 14;
                ramp_amps = round(linspace(min_amp, p1_amp, 15));
                ramp_wfs = cell(14, 2);
                for ramp_ix = 1:14
                    ramp_params.p1_amp = ramp_amps(ramp_ix);
                    [ramp_wfs{ramp_ix,1}, ramp_wfs{ramp_ix,2}] =...
                        app.calc_waveform(ramp_params);
                    app.stimulator.setStimPattern(...
                        'waveform', ramp_ix,...
                        ramp_wfs{ramp_ix,1}{:});
                end
            end
            % Add waveforms x reps to stimulator program.
            app.stimulator.beginSequence();
            % Add ramp stims
            for ramp_ix = 1:size(ramp_wfs, 1)
                for stim_ix = 1:ramp_wfs{ramp_ix, 2}
                    app.stimulator.autoStim(stim_params.elec, ramp_ix);
                end
            end
            % Add final-amp stims
            for final_ix = 1:n_final_stims
                app.stimulator.autoStim(stim_params.elec, 15);
            end
            app.stimulator.endSequence();
            
            app.StartButton.Enable = 'on';
            app.StatusLamp.Color = 'blue';
            
            delete(app.stim_timer);
            app.stim_timer = timer('StopFcn', @app.timer_callback,...
                'TimerFcn', @app.timer_callback,...
                'StartDelay', app.FinalDursEditField.Value);
        end

        % Value changed function: InterphaseDropDown
        function InterphaseDropDownValueChanged(app, event)
            value = app.InterphaseDropDown.Value;
            app.params_changed();
        end

        % Value changed function: PolarityDropDown
        function PolarityDropDownValueChanged(app, event)
            value = app.PolarityDropDown.Value;
            app.params_changed();
        end

        % Value changed function: RampDursEditField
        function RampDursEditFieldValueChanged(app, event)
            value = app.RampDursEditField.Value;
            app.params_changed();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            device_list = app.stimulator.scanForDevices();
            dd_items = cell(1, length(device_list));
            for dev_ix = 1:length(device_list)
                dd_items{dev_ix} = num2str(device_list(dev_ix));
            end
            app.DropDown.Items = dd_items;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            app.stimulator.play(1);
            start(app.stim_timer);  % Start timer.
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stimulator.stop();
            stop(app.stim_timer);  % Cancel timer, change lamp to blue.
            delete(app.stim_timer);
        end

        % Callback function
        function WidthuSEditFieldValueChanged(app, event)
            value = app.WidthuSEditField.Value;
            app.params_changed();
        end

        % Value changed function: WidthuSSpinner
        function WidthuSSpinnerValueChanged(app, event)
            value = app.WidthuSSpinner.Value;
            app.params_changed();
        end

        % Key press function: CereStimDBSUIFigure
        function CereStimDBSUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'space'
                    if all(app.StatusLamp.Color == [0 1 0])
                        % Green
                        % TODO: Check instead app.stim_timer?
                        app.StopButtonPushed(event);
                    elseif all(app.StatusLamp.Color == [0 0 1])
                        % Blue
                        app.StartButtonPushed(event);
                    elseif all(app.StatusLamp.Color == [1 1 0])
                        % Yellow
                        app.GenerateButtonPushed(event);
                    end
                case 'rightarrow'
                    app.AmplitudeuASpinner.Value = app.AmplitudeuASpinner.Value + 100;
                    app.params_changed();
                case 'leftarrow'
                    app.AmplitudeuASpinner.Value = app.AmplitudeuASpinner.Value - 100;
                    app.params_changed();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CereStimDBSUIFigure = uifigure('Visible', 'off');
            app.CereStimDBSUIFigure.Name = 'CereStim DBS';
            app.CereStimDBSUIFigure.CloseRequestFcn = createCallbackFcn(app, @CereStimDBSUIFigureCloseRequest, true);
            app.CereStimDBSUIFigure.KeyPressFcn = createCallbackFcn(app, @CereStimDBSUIFigureKeyPress, true);
            app.RefreshButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DropDown = uidropdown(app.CereStimDBSUIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.ConnectButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FrequencyHzEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.FrequencyHzEditField.Limits = [4 5000];
            app.FrequencyHzEditField.ValueDisplayFormat = '%11.12g';
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 300;
            app.FinalDursEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.FinalDursEditFieldLabel.Text = 'Final Dur. (s)';
            app.FinalDursEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.FinalDursEditField.Limits = [1 Inf];
            app.FinalDursEditField.ValueChangedFcn = createCallbackFcn(app, @FinalDursEditFieldValueChanged, true);
            app.FinalDursEditField.Value = 30;
            app.RampDursEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.RampDursEditFieldLabel.Text = 'Ramp Dur. (s)';
            app.RampDursEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.RampDursEditField.Limits = [0 5];
            app.RampDursEditField.ValueChangedFcn = createCallbackFcn(app, @RampDursEditFieldValueChanged, true);
            app.InterphaseDropDownLabel = uilabel(app.CereStimDBSUIFigure);
            app.InterphaseDropDownLabel.Text = 'Interphase';
            app.InterphaseDropDown = uidropdown(app.CereStimDBSUIFigure);
            app.InterphaseDropDown.Items = {'Max Sep', 'Min Sep'};
            app.InterphaseDropDown.ValueChangedFcn = createCallbackFcn(app, @InterphaseDropDownValueChanged, true);
            app.InterphaseDropDown.Value = 'Min Sep';
            app.PolarityDropDownLabel = uilabel(app.CereStimDBSUIFigure);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.CereStimDBSUIFigure);
            app.PolarityDropDown.Items = {'Cathodic First', 'Anodic First', 'Cathodic-Mono', 'Anodic-Mono'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityDropDownValueChanged, true);
            app.PolarityDropDown.Value = 'Cathodic First';
            app.GenerateButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.StartButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLampLabel = uilabel(app.CereStimDBSUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CereStimDBSUIFigure);
            app.WidthuSSpinnerLabel = uilabel(app.CereStimDBSUIFigure);
            app.WidthuSSpinnerLabel.Text = 'Width (uS)';
            app.WidthuSSpinner = uispinner(app.CereStimDBSUIFigure);
            app.WidthuSSpinner.Step = 100;
            app.WidthuSSpinner.Limits = [44 65535];
            app.WidthuSSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthuSSpinnerValueChanged, true);
            app.WidthuSSpinner.Value = 100;
            app.ElectrodeSpinnerLabel = uilabel(app.CereStimDBSUIFigure);
            app.ElectrodeSpinnerLabel.Text = 'Electrode';
            app.ElectrodeSpinner = uispinner(app.CereStimDBSUIFigure);
            app.ElectrodeSpinner.Limits = [1 96];
            app.ElectrodeSpinner.ValueChangedFcn = createCallbackFcn(app, @ElectrodeSpinnerValueChanged, true);
            app.ElectrodeSpinner.Value = 1;
            app.AmplitudeuASpinnerLabel = uilabel(app.CereStimDBSUIFigure);
            app.AmplitudeuASpinnerLabel.Text = 'Amplitude (uA)';
            app.AmplitudeuASpinner = uispinner(app.CereStimDBSUIFigure);
            app.AmplitudeuASpinner.Step = 100;
            app.AmplitudeuASpinner.Limits = [100 10000];
            app.AmplitudeuASpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeuASpinnerValueChanged, true);
            app.AmplitudeuASpinner.Value = 500;
            % Show the figure after all components are created
            app.CereStimDBSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cerestim_dbs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CereStimDBSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CereStimDBSUIFigure)
        end
    end
end